classdef enf4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        balanceTab                  matlab.ui.container.Tab
        FrecuenciaEditFieldLabel    matlab.ui.control.Label
        FrecuenciaEditField         matlab.ui.control.EditField
        ButtonGroup3                matlab.ui.container.ButtonGroup
        Button                      matlab.ui.control.RadioButton
        Button2                     matlab.ui.control.RadioButton
        Button3                     matlab.ui.control.RadioButton
        Button5                     matlab.ui.control.Button
        ruidoTab                    matlab.ui.container.Tab
        CulgasSpinnerLabel          matlab.ui.control.Label
        CulgasSpinner               matlab.ui.control.Spinner
        Button4                     matlab.ui.control.Button
        perdidasTab                 matlab.ui.container.Tab
        TabGroup2                   matlab.ui.container.TabGroup
        EspaciolibreTab             matlab.ui.container.Tab
        DesapuntalamintoTab         matlab.ui.container.Tab
        FrecuenciaEditField_2Label  matlab.ui.control.Label
        FrecuenciaEditField_2       matlab.ui.control.NumericEditField
        DistanciaEditFieldLabel     matlab.ui.control.Label
        DistanciaEditField          matlab.ui.control.NumericEditField
        CalcularButton              matlab.ui.control.Button
        ResultLfs                   matlab.ui.control.NumericEditField
        ParmetrosGeneralesdeEntradaLabel  matlab.ui.control.Label
        DesapuenTxEditFieldLabel    matlab.ui.control.Label
        DesapuenTxEditField         matlab.ui.control.NumericEditField
        DesapuenRxEditFieldLabel    matlab.ui.control.Label
        DesapuenRxEditField         matlab.ui.control.NumericEditField
        AnchoHazenTxEditFieldLabel  matlab.ui.control.Label
        AnchoHazenTxEditField       matlab.ui.control.NumericEditField
        AnchoHazenRxEditFieldLabel  matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        Label_2                     matlab.ui.control.Label
        Label_3                     matlab.ui.control.Label
        Label_4                     matlab.ui.control.Label
        CalcularDesa                matlab.ui.control.Button
        ResultDesap                 matlab.ui.control.NumericEditField
        AnchoHazenRxEditField       matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function LibreButtonPushed(app, event)
            res=32.45+ 20*log(app.freq.Value)+20*log(app.radio_ob.Value)
            app.esp_libre.Text=num2str(res)+"dB";
        end

        % Callback function
        function LfsValueChanged(app, event)
            value = LibreButtonPushed.res;
            
        end

        % Button pushed function: CalcularButton
        function CalcularButtonPushed(app, event)
            result = 32.45 + 20*log(app.FrecuenciaEditField_2 ...
                .Value) + 20*log(app.DistanciaEditField.Value);            
            app.ResultLfs.Value = result;
        end

        % Button pushed function: CalcularDesa
        function CalcularDesaButtonPushed(app, event)
            result = 12*(app.DesapuenTxEditField.Value/app.AnchoHazenTxEditField.Value)^2 + ...
            12*(app.DesapuenRxEditField.Value/app.DesapuenRxEditField.Value)^2;
            
            app.ResultDesap.Value = result;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ParmetrosGeneralesdeEntradaLabel = uilabel(app.UIFigure);
            app.ParmetrosGeneralesdeEntradaLabel.Text = 'Parámetros Generales de Entrada';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.balanceTab = uitab(app.TabGroup);
            app.balanceTab.Title = 'balance';
            app.FrecuenciaEditFieldLabel = uilabel(app.balanceTab);
            app.FrecuenciaEditFieldLabel.Text = 'Frecuencia';
            app.FrecuenciaEditField = uieditfield(app.balanceTab, 'text');
            app.ButtonGroup3 = uibuttongroup(app.balanceTab);
            app.ButtonGroup3.Title = 'Button Group3';
            app.Button = uiradiobutton(app.ButtonGroup3);
            app.Button.Text = 'Button';
            app.Button.Value = true;
            app.Button2 = uiradiobutton(app.ButtonGroup3);
            app.Button2.Text = 'Button2';
            app.Button3 = uiradiobutton(app.ButtonGroup3);
            app.Button3.Text = 'Button3';
            app.Button5 = uibutton(app.balanceTab, 'push');
            app.Button5.Text = 'Button5';
            app.ruidoTab = uitab(app.TabGroup);
            app.ruidoTab.Title = 'ruido';
            app.CulgasSpinnerLabel = uilabel(app.ruidoTab);
            app.CulgasSpinnerLabel.Text = '¿Cuálgas?';
            app.CulgasSpinner = uispinner(app.ruidoTab);
            app.Button4 = uibutton(app.ruidoTab, 'push');
            app.Button4.Text = 'Button4';
            app.perdidasTab = uitab(app.TabGroup);
            app.perdidasTab.Title = 'perdidas';
            app.TabGroup2 = uitabgroup(app.perdidasTab);
            app.EspaciolibreTab = uitab(app.TabGroup2);
            app.EspaciolibreTab.Title = 'Espacio libre';
            app.FrecuenciaEditField_2Label = uilabel(app.EspaciolibreTab);
            app.FrecuenciaEditField_2Label.Text = 'Frecuencia';
            app.FrecuenciaEditField_2 = uieditfield(app.EspaciolibreTab, 'numeric');
            app.DistanciaEditFieldLabel = uilabel(app.EspaciolibreTab);
            app.DistanciaEditFieldLabel.Text = 'Distancia';
            app.DistanciaEditField = uieditfield(app.EspaciolibreTab, 'numeric');
            app.ResultLfs = uieditfield(app.EspaciolibreTab, 'numeric');
            app.CalcularButton = uibutton(app.EspaciolibreTab, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular';
            app.DesapuntalamintoTab = uitab(app.TabGroup2);
            app.DesapuntalamintoTab.Title = 'Desapuntalaminto';
            app.DesapuenTxEditFieldLabel = uilabel(app.DesapuntalamintoTab);
            app.DesapuenTxEditFieldLabel.Text = 'Desapu. en Tx';
            app.DesapuenTxEditField = uieditfield(app.DesapuntalamintoTab, 'numeric');
            app.DesapuenRxEditFieldLabel = uilabel(app.DesapuntalamintoTab);
            app.DesapuenRxEditFieldLabel.Text = 'Desapu. en Rx';
            app.DesapuenRxEditField = uieditfield(app.DesapuntalamintoTab, 'numeric');
            app.AnchoHazenTxEditFieldLabel = uilabel(app.DesapuntalamintoTab);
            app.AnchoHazenTxEditFieldLabel.Text = 'Ancho Haz en Tx';
            app.AnchoHazenTxEditField = uieditfield(app.DesapuntalamintoTab, 'numeric');
            app.AnchoHazenRxEditFieldLabel = uilabel(app.DesapuntalamintoTab);
            app.AnchoHazenRxEditFieldLabel.Text = 'Ancho Haz en Rx';
            app.AnchoHazenRxEditField = uieditfield(app.DesapuntalamintoTab, 'numeric');
            app.Label = uilabel(app.DesapuntalamintoTab);
            app.Label.Text = '°';
            app.Label_2 = uilabel(app.DesapuntalamintoTab);
            app.Label_2.Text = '°';
            app.Label_3 = uilabel(app.DesapuntalamintoTab);
            app.Label_3.Text = '°';
            app.Label_4 = uilabel(app.DesapuntalamintoTab);
            app.Label_4.Text = '°';
            app.ResultDesap = uieditfield(app.DesapuntalamintoTab, 'numeric');
            app.CalcularDesa = uibutton(app.DesapuntalamintoTab, 'push');
            app.CalcularDesa.ButtonPushedFcn = createCallbackFcn(app, @CalcularDesaButtonPushed, true);
            app.CalcularDesa.Text = 'Calcular';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = enf4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end