classdef interfaz_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PatientsMedicalSurveyUIFigure   matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        ValoresdeReferenciaLabel        matlab.ui.control.Label
        DistanciaEditFieldLabel         matlab.ui.control.Label
        DistanciaEditField              matlab.ui.control.NumericEditField
        KmLabel                         matlab.ui.control.Label
        FijarDatosButton                matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        LatitudEditFieldLabel           matlab.ui.control.Label
        LatitudEditField                matlab.ui.control.NumericEditField
        Label_9                         matlab.ui.control.Label
        ElevacionEditFieldLabel         matlab.ui.control.Label
        ElevacionEditField              matlab.ui.control.NumericEditField
        Label_14                        matlab.ui.control.Label
        BandaDropDownLabel              matlab.ui.control.Label
        BandaDropDown                   matlab.ui.control.DropDown
        RightPanel                      matlab.ui.container.Panel
        TabGroup3                       matlab.ui.container.TabGroup
        PerdidasTab                     matlab.ui.container.Tab
        TabGroup4                       matlab.ui.container.TabGroup
        BasicasTab                      matlab.ui.container.Tab
        CalcularButton_2                matlab.ui.control.Button
        libre                           matlab.ui.control.NumericEditField
        dBLabel_4                       matlab.ui.control.Label
        Lfs_downEditFieldLabel          matlab.ui.control.Label
        Lfs_downEditField               matlab.ui.control.NumericEditField
        Lfs_upLabel                     matlab.ui.control.Label
        dBLabel_11                      matlab.ui.control.Label
        DesapuntamientoTab              matlab.ui.container.Tab
        DesapenTxEditFieldLabel         matlab.ui.control.Label
        DesapenTxEditField              matlab.ui.control.NumericEditField
        CalcularButton_3                matlab.ui.control.Button
        DesapenRxEditFieldLabel         matlab.ui.control.Label
        DesapenRxEditField              matlab.ui.control.NumericEditField
        Ancho3dBEditFieldLabel          matlab.ui.control.Label
        Ancho3dBEditField               matlab.ui.control.NumericEditField
        desap                           matlab.ui.control.NumericEditField
        dBLabel_5                       matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        GasesTab_2                      matlab.ui.container.Tab
        CalcularButton_4                matlab.ui.control.Button
        pgases                          matlab.ui.control.NumericEditField
        dBLabel_6                       matlab.ui.control.Label
        PresionEditFieldLabel           matlab.ui.control.Label
        PresionEditField                matlab.ui.control.NumericEditField
        DensidaddelAguaEditFieldLabel   matlab.ui.control.Label
        DensidadEditField               matlab.ui.control.NumericEditField
        TemperaturaEditField_2Label     matlab.ui.control.Label
        TemperaturaEditField_2          matlab.ui.control.NumericEditField
        hPaLabel_2                      matlab.ui.control.Label
        gm3Label_2                      matlab.ui.control.Label
        CLabel_2                        matlab.ui.control.Label
        UplinkEditFieldLabel            matlab.ui.control.Label
        GasesUplinkEditField            matlab.ui.control.NumericEditField
        DownlinkLabel_2                 matlab.ui.control.Label
        dBLabel_12                      matlab.ui.control.Label
        LluviaTab_2                     matlab.ui.container.Tab
        KmLabel_2                       matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        mmhLabel                        matlab.ui.control.Label
        CalcularButton_6                matlab.ui.control.Button
        downplluvia                     matlab.ui.control.NumericEditField
        dBLabel_8                       matlab.ui.control.Label
        Label_13                        matlab.ui.control.Label
        FigH0Button                     matlab.ui.control.Button
        TablaKyAButton                  matlab.ui.control.Button
        PBajdaLabel                     matlab.ui.control.Label
        Up_plluvia                      matlab.ui.control.NumericEditField
        dBLabel_10                      matlab.ui.control.Label
        PSubidaLabel                    matlab.ui.control.Label
        ITUP837Button                   matlab.ui.control.Button
        FactorKupEditFieldLabel         matlab.ui.control.Label
        FactorKupEditField              matlab.ui.control.NumericEditField
        FactorAupEditFieldLabel         matlab.ui.control.Label
        FactorAupEditField              matlab.ui.control.NumericEditField
        AlturahsEditFieldLabel          matlab.ui.control.Label
        AlturahsEditField               matlab.ui.control.NumericEditField
        h0EditFieldLabel                matlab.ui.control.Label
        h0EditField                     matlab.ui.control.NumericEditField
        IndiponibilidadpEditFieldLabel  matlab.ui.control.Label
        IndiponibilidadpEditField       matlab.ui.control.NumericEditField
        IntensidadLluviaEditFieldLabel  matlab.ui.control.Label
        IntensidadLluviaEditField       matlab.ui.control.NumericEditField
        LongitudEditFieldLabel          matlab.ui.control.Label
        LongitudEditField               matlab.ui.control.NumericEditField
        FactorKdownEditFieldLabel       matlab.ui.control.Label
        FactorKdownEditField            matlab.ui.control.NumericEditField
        FactorAdownEditFieldLabel       matlab.ui.control.Label
        FactorAdownEditField            matlab.ui.control.NumericEditField
        OtrasPerdTab                    matlab.ui.container.Tab
        ResultOtrasper                  matlab.ui.control.NumericEditField
        CalcularOtrasper                matlab.ui.control.Button
        CentelleoLabel                  matlab.ui.control.Label
        UplinkEditFieldLabel_2          matlab.ui.control.Label
        CentelleoUplinkEditField        matlab.ui.control.NumericEditField
        DownlinkLabel_3                 matlab.ui.control.Label
        TotalTab_2                      matlab.ui.container.Tab
        CalcularButton_5                matlab.ui.control.Button
        ptotal                          matlab.ui.control.NumericEditField
        dBLabel_7                       matlab.ui.control.Label
        UplinkEditFieldLabel_3          matlab.ui.control.Label
        UplinkTotalEditField            matlab.ui.control.NumericEditField
        DownlinkLabel_4                 matlab.ui.control.Label
        RuidoTab                        matlab.ui.container.Tab
        TabGroup5                       matlab.ui.container.TabGroup
        AntenaTab                       matlab.ui.container.Tab
        Switch                          matlab.ui.control.Switch
        CalcularButton_8                matlab.ui.control.Button
        res_antena                      matlab.ui.control.NumericEditField
        TgEditFieldLabel                matlab.ui.control.Label
        TgEditField                     matlab.ui.control.NumericEditField
        GlsEditFieldLabel               matlab.ui.control.Label
        GlsEditField                    matlab.ui.control.NumericEditField
        TskyEditFieldLabel              matlab.ui.control.Label
        TskyEditField                   matlab.ui.control.NumericEditField
        TatmEditFieldLabel              matlab.ui.control.Label
        TatmEditField                   matlab.ui.control.NumericEditField
        TwacoploEditFieldLabel          matlab.ui.control.Label
        TwacoploEditField               matlab.ui.control.NumericEditField
        wacoploEditFieldLabel           matlab.ui.control.Label
        wacoploEditField                matlab.ui.control.NumericEditField
        ReceptorTab                     matlab.ui.container.Tab
        ValoresLabel                    matlab.ui.control.Label
        Valores_g                       matlab.ui.control.EditField
        EjsillggLabel                   matlab.ui.control.Label
        CalcularButton_7                matlab.ui.control.Button
        ResultadoRuido                  matlab.ui.control.NumericEditField
        Label_12                        matlab.ui.control.Label
        OrdenEditFieldLabel             matlab.ui.control.Label
        Orden                           matlab.ui.control.EditField
        TemperaturaEditField_3Label     matlab.ui.control.Label
        TemperaturaRuido                matlab.ui.control.EditField
        Siaplica2sinoescribir0Label     matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        Ej12151Label                    matlab.ui.control.Label
        TodoslosvaloresdebenestarensuspropiostrminosyenvecesLabel  matlab.ui.control.Label
        AplicacuandoenelordenexistegLabel  matlab.ui.control.Label
        SiesgelvaloresenglomismoparaLNohacerconversionesLabel  matlab.ui.control.Label
        RuidoSistemaTab                 matlab.ui.container.Tab
        TemperaturadeBrilloEditFieldLabel  matlab.ui.control.Label
        TemperaturadeBrilloEditField    matlab.ui.control.NumericEditField
        CalcularButton_9                matlab.ui.control.Button
        EnlacedeSubidaEditFieldLabel    matlab.ui.control.Label
        EnlacedeSubidaEditField         matlab.ui.control.NumericEditField
        EnlacedeBajadaEditFieldLabel    matlab.ui.control.Label
        EnlacedeBajadaEditField         matlab.ui.control.NumericEditField
        BackoffTab                      matlab.ui.container.Tab
        Switch_2                        matlab.ui.control.Switch
        CalcularButton_10               matlab.ui.control.Button
        PireinEditFieldLabel            matlab.ui.control.Label
        PireinEditField                 matlab.ui.control.NumericEditField
        PireoutEditFieldLabel           matlab.ui.control.Label
        PireoutEditField                matlab.ui.control.NumericEditField
        IBEditFieldLabel                matlab.ui.control.Label
        IBEditField                     matlab.ui.control.NumericEditField
        OBEditFieldLabel                matlab.ui.control.Label
        OBEditField                     matlab.ui.control.NumericEditField
        pireinsat                       matlab.ui.control.NumericEditField
        pireoutsat                      matlab.ui.control.NumericEditField
        PireinsatLabel                  matlab.ui.control.Label
        PireoutsatLabel                 matlab.ui.control.Label
        DeEntradaLabel                  matlab.ui.control.Label
        dBmLabel                        matlab.ui.control.Label
        dBmLabel_2                      matlab.ui.control.Label
        ConPireptxgtxLabel              matlab.ui.control.Label
        GTTab                           matlab.ui.container.Tab
        GananciaEstacionTerrenaEditField  matlab.ui.control.NumericEditField
        GananciaSateliteEditFieldLabel  matlab.ui.control.Label
        GananciaSateliteEditField       matlab.ui.control.NumericEditField
        DownlinkLabel                   matlab.ui.control.Label
        UplinkLabel                     matlab.ui.control.Label
        CalcularGT                      matlab.ui.control.Button
        ResultGT                        matlab.ui.control.NumericEditField
        Switch_3                        matlab.ui.control.Switch
        GananciaEstacionTerrenaLabel    matlab.ui.control.Label
        Dejaren0sinosedesealaGTobjetivoLabel  matlab.ui.control.Label
        NotalagananciadebeestarendBLabel  matlab.ui.control.Label
        dBLabel_9                       matlab.ui.control.Label
        CNTab                           matlab.ui.container.Tab
        TabGroup6                       matlab.ui.container.TabGroup
        UpLinkTab                       matlab.ui.container.Tab
        CalcularCNUp                    matlab.ui.control.Button
        ResultCnUp                      matlab.ui.control.NumericEditField
        PIREDdEditFieldLabel            matlab.ui.control.Label
        PIREDdEditField                 matlab.ui.control.NumericEditField
        OBEditField_2Label              matlab.ui.control.Label
        OBEditField_2                   matlab.ui.control.NumericEditField
        LfdEditFieldLabel               matlab.ui.control.Label
        LfdEditField                    matlab.ui.control.NumericEditField
        LdEditFieldLabel                matlab.ui.control.Label
        LdEditField                     matlab.ui.control.NumericEditField
        GTEditFieldLabel                matlab.ui.control.Label
        GTEditField                     matlab.ui.control.NumericEditField
        DownlinkTab                     matlab.ui.container.Tab
        CalcularCnD                     matlab.ui.control.Button
        EditField_3                     matlab.ui.control.NumericEditField
        TotalTab_3                      matlab.ui.container.Tab
        EditField                       matlab.ui.control.NumericEditField
        CalcularButton_11               matlab.ui.control.Button
        CapacidadTab                    matlab.ui.container.Tab
        CloseButton                     matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        Data % Patient data
        %-----------------VARIABLES GLOBALES-----------------------
        Resultlfs
        ResultDesapun
        ResultGases
        ResultLluvia
        ResultOtrasPer
        ResultPerdTot    
        ResultAnt
        ResultTrx
        ResultRuidoTot
        ResultGT_sat
        ResultGT_et
        %banda = app.BandaDropDown.Value;
        fr_up
        fr_d
        
    end
    
    methods (Access = private)
        
        function enableForm(app)
            % Enable form elements
            app.NameEditField.Enable = 'on';
            app.AgeEditField.Enable = 'on';
            app.GenderDropDown.Enable = 'on';
            app.HealthStatusDropDown.Enable = 'on';
            app.SmokerCheckBox.Enable = 'on';

           app.NameEditFieldLabel.Enable = 'on';            
            app.AgeEditFieldLabel.Enable = 'on';
           app.GenderDropDownLabel.Enable = 'on';
            app.HealthStatusDropDownLabel.Enable = 'on';
        end
        
        function disableForm(app)
            % Set form fields to default values
            app.NameEditField.Value = '';
            app.AgeEditField.Value = 0;
            app.GenderDropDown.Value = 'Male';
            app.HealthStatusDropDown.Value = 'Excellent';
            app.SmokerCheckBox.Value = false;
            
            % Disable form fields
            app.NameEditField.Enable = 'off';
            app.AgeEditField.Enable = 'off';
            app.GenderDropDown.Enable = 'off';
            app.HealthStatusDropDown.Enable = 'off';
            app.SmokerCheckBox.Enable = 'off'; 
                        
            app.NameEditFieldLabel.Enable = 'off';            
            app.AgeEditFieldLabel.Enable = 'off';
            app.GenderDropDownLabel.Enable = 'off';
            app.HealthStatusDropDownLabel.Enable = 'off';
        end
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.PatientsMedicalSurveyUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {443, 443};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {253, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Close request function: PatientsMedicalSurveyUIFigure
        function CalcularButtonPushed(app, event)

           % if app.FrecuenciaEditField.Value > 50
                %f = app.UIFigure;
                %uialert(f,'Valor de frecuencia invalida, deber ser menor de 50','Error');
            %end
           % result = 32.45 + 20*log10(app.FrecuenciaEditField.Value*1000) ...
               % + 20*log10(app.DistanciaEditField.Value);            
            %app.ResultLfs.Value = result;
        end

        % Callback function
        function CalcularDesaButtonPushed(app, event)
            result = 12*(app.DesapuenTxEditField.Value/app.AnchoHazenTxEditField.Value)^2 + ...
            12*(app.DesapuenRxEditField.Value/app.DesapuenRxEditField.Value)^2;
           
            app.ResultDesap.Value = result;
            app.ResultDesapun = result;
        end

        % Callback function
        function Calcular_aten_gasesButtonPushed(app, event)
            try
            result = atenuacionGases(app.PresinEditField.Value,...
                app.TemperaturaEditField.Value, app.DensidaddelaguaEditField.Value,...
                app.DistanciaEditField.Value,app.FrecuenciaEditField.Value);
            app.ResultGases.Value = result;
            app.ResultGases=result;
        catch
            msgbox('Datos erroneos', 'Error','error');
            end 
        end

        % Button pushed function: FijarDatosButton
        function FijarDatosButtonPushed(app, event)
            if app.FrecuenciaEditField.Value < 56 && (app.FrecuenciaEditField.Value ~= 0 || app.DistanciaEditField.Value ~= 0 )
                app.TabGroup3.Visible = 'on';
            elseif app.FrecuenciaEditField.Value >= 56
                    msgbox('Frecuncia invalida, debe ingresar una frecuncia menor de 56GHz', 'Error','error');
                    app.FrecuenciaEditField.Value = 0;                
                elseif app.FrecuenciaEditField.Value == 0 || app.DistanciaEditField.Value == 0 
                    msgbox('Valores en cero', 'Error','error');
               end             
            
        end

        % Callback function
        function CalcularTotalButtonPushed(app, event)
            app.ResultTotal.Value = app.ResultLfs.Value + app.ResultDesap.Value...
                + app.ResultGases.Value;
            app.ResultPerdTot = app.ResultTotal.Value;
        end

        % Button pushed function: CalcularButton_2
        function CalcularButton_2Pushed(app, event)
            %result = 32.45 + 20*log10(app.FrecuenciaEditField.Value*1000) ...
                %+ 20*log10(app.DistanciaEditField.Value);    
                app.fr_up
            result = 32.45 + 20*log10(app.fr_up*1000) ...
                + 20*log10(app.DistanciaEditField.Value);            
            app.libre.Value = result;
            app.Lfs_downEditField.Value = 32.45 + 20*log10(app.fr_d*1000) ...
                + 20*log10(app.DistanciaEditField.Value);            
            app.libre.Value = result;
            app.Resultlfs = result;           
        end

        % Button pushed function: CalcularButton_3
        function CalcularButton_3Pushed(app, event)
            result = 12*(app.DesapenTxEditField.Value/app.Ancho3dBEditField.Value)^2 + ...
            12*(app.DesapenRxEditField.Value/app.Ancho3dBEditField.Value)^2;
           
            app.desap.Value = result;
            app.ResultDesapun = result;           
        end

        % Button pushed function: CalcularButton_4
        function CalcularButton_4Pushed(app, event)
            try
                app.GasesUplinkEditField.Value = atenuacionGases(app.PresionEditField.Value,...
                app.TemperaturaEditField_2.Value, app.DensidadEditField.Value,...
                app.DistanciaEditField.Value,app.fr_up);
            result = atenuacionGases(app.PresionEditField.Value,...
                app.TemperaturaEditField_2.Value, app.DensidadEditField.Value,...
                app.DistanciaEditField.Value,app.fr_d);
            app.pgases.Value = result;
        catch
            msgbox('Datos erroneos', 'Error','error');
            end 
        end

        % Button pushed function: CalcularButton_5
        function CalcularButton_5Pushed(app, event)
            %app.ptotal.Value = app.libre.Value + app.desap.Value...
               % + app.pgases.Value+app.downplluvia.Value;
            
                a=app.libre.Value;
                b=app.desap.Value;
                c=app.pgases.Value;
                d=app.downplluvia.Value;
                %agregar nuevas perdidas
                app.ptotal.Value=10*log10( 10^(a/10) +10^(b/10) +10^(c/10) +10^(d/10) );
                a=app.Lfs_downEditField.Value;                
                c=app.GasesUplinkEditField.Value;
                d=app.Up_plluvia.Value;
                app.UplinkTotalEditField.Value = 10*log10( 10^(a/10) +10^(c/10) +10^(d/10) );
        end

        % Button pushed function: CalcularButton_6
        function CalcularButton_6Pushed(app, event)
            app.Up_plluvia.Value = perdidas_lluvia(app.fr_up,app.LatitudEditField.Value,...
                app.LongitudEditField.Value, app.ElevacionEditField.Value, ...
             app.IntensidadLluviaEditField.Value, app.FactorKupEditField.Value, app.FactorAupEditField.Value,...
             app.AlturahsEditField.Value, app.h0EditField.Value, app.IndiponibilidadpEditField.Value);
         
            app.downplluvia.Value=perdidas_lluvia(app.fr_d,app.LatitudEditField.Value,...
                app.LongitudEditField.Value, app.ElevacionEditField.Value, ...
             app.IntensidadLluviaEditField.Value, app.FactorKupEditField.Value, app.FactorAupEditField.Value,...
             app.AlturahsEditField.Value, app.h0EditField.Value, app.IndiponibilidadpEditField.Value);
         app.ResultLluvia = app.downplluvia.Value;
        end

        % Button pushed function: CalcularOtrasper
        function CalcularOtrasperButtonPushed(app, event)
            app.CentelleoUplinkEditField.Value = centelleo(app.fr_up,...
                app.ElevacionEditField.Value, app.LatitudEditField.Value);
            app.ResultOtrasper.Value = centelleo(app.fr_d,...
                app.ElevacionEditField.Value, app.LatitudEditField.Value);
            app.ResultOtrasPer = app.ResultOtrasper.Value;
        end

        % Button pushed function: CalcularButton_7
        function CalcularButton_7Pushed(app, event)
        array_tipo=string(split(app.Orden.Value))    
        array_g=double(string(split(app.Valores_g.Value)));
        array_temp=double(string(split(app.TemperaturaRuido.Value)));
        %disp('resultados')
        %disp(length(array_tipo))
        %disp(length(array_g))
        %disp(length(array_temp))
            ruido_total=ruido_recepcion(array_g, array_tipo, array_temp);
            app.ResultadoRuido.Value=ruido_total;
            app.ResultTrx = ruido_total;
        end

        % Button pushed function: CalcularButton_8
        function CalcularButton_8Pushed(app, event)
            tipo=app.Switch.Value;
            Tg=app.TgEditField.Value;
            gls=app.GlsEditField.Value;
            Ts=app.TskyEditField.Value;
            Tat=app.TatmEditField.Value;
            ag=app.pgases.Value;
            al=app.downplluvia.Value;
            Tw=app.TwacoploEditField.Value;
            w=app.wacoploEditField.Value;
            res_antena=ruido_antena(tipo,Tg,gls,Ts,Tat,ag,al,Tw,w);
            app.res_antena.Value=res_antena;
            
            app.ResultAnt = res_antena;
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            closereq;
        end

        % Button pushed function: CalcularButton_9
        function CalcularButton_9Pushed(app, event)
            ruido_antena=app.res_antena.Value;
            ruido_recepcion=app.ResultadoRuido.Value;
            temperaratura_brillo=app.TemperaturadeBrilloEditField.Value
            [temperatura_up temperatura_down]=ruido_total(ruido_antena, ruido_recepcion, temperaratura_brillo)
            app.EnlacedeSubidaEditField.Value=temperatura_up;
            app.EnlacedeBajadaEditField.Value=temperatura_down;
            
        end

        % Button pushed function: CalcularButton_10
        function CalcularButton_10Pushed(app, event)
        if string(app.Switch_2.Value)=="TDMA"
            %saturacion se habilita
            app.pireinsat.Value=app.PireinEditField.Value+app.IBEditField.Value;
            app.pireoutsat.Value=app.PireoutEditField.Value+app.OBEditField.Value;
        else
            %saturacion no permitida
           app.pireinsat.Value=app.PireinEditField.Value;
            app.pireoutsat.Value=app.PireoutEditField.Value;
        end
        
        end

        % Button pushed function: CalcularGT
        function CalcularGTPushed(app, event)
            if string(app.Switch_3.Value)=="Estacion"
                app.ResultGT_et = g_tET(app.GananciaEstacionTerrenaEditField.Value,app.EnlacedeSubidaEditField.Value);
                app.ResultGT.Value = app.ResultGT_et;
            else
                app.ResultGT_sat = g_tET(app.GananciaSateliteEditField.Value,app.EnlacedeBajadaEditField.Value);
                app.ResultGT.Value = app.ResultGT_sat;
            end
            
            
        end

        % Button pushed function: CalcularCNUp
        function CalcularCNUpButtonPushed(app, event)
            app.ResultCnUp.Value= cn_up(app.PIREDdEditField.Value,...
                app.OBEditField_2.Value, app.LfdEditField.Value, app.LdEditField.Value,...
                app.GTEditField.Value);
        end

        % Button pushed function: CalcularCnD
        function CalcularCnDButtonPushed(app, event)
            app.EditField_3.Value= cn_up(app.PIREDdEditField.Value,...
                app.OBEditField_2.Value, app.LfdEditField.Value, app.LdEditField.Value,...
                app.GTEditField.Value);
        end

        % Value changed function: BandaDropDown
        function BandaDropDownValueChanged(app, event)
            bandaD = app.BandaDropDown.Value;
            if bandaD == 'C'
                app.fr_up = 6; 
                app.fr_d = 4;
                app.LluviaTab_2.Ena
            
            elseif bandaD == 'Ku'
                app.fr_up = 14; 
                app.fr_d = 12;
            
            elseif bandaD == 'Ka'
                app.fr_up = 30; 
                app.fr_d = 20;
            
            elseif bandaD == 'L'
                app.fr_up = 1.6; 
                app.fr_d = 1.5;
            end
        end

        % Button pushed function: FigH0Button
        function FigH0ButtonPushed(app, event)
            %[corn_rgb] = imread('h0.jpg',2);
            imshow('h0.jpg');
        end

        % Button pushed function: TablaKyAButton
        function TablaKyAButtonPushed(app, event)
            imshow('kyalfa.png');
        end

        % Button pushed function: ITUP837Button
        function ITUP837ButtonPushed(app, event)
            imshow('R001.png');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PatientsMedicalSurveyUIFigure = uifigure('Visible', 'off');
            app.PatientsMedicalSurveyUIFigure.Name = 'SIMULADOR SATELITAL';
            app.PatientsMedicalSurveyUIFigure.CloseRequestFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.PatientsMedicalSurveyUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.PatientsMedicalSurveyUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanel.Title = 'GRUPO SPUTNIK';
            app.ValoresdeReferenciaLabel = uilabel(app.LeftPanel);
            app.ValoresdeReferenciaLabel.Text = 'Valores de Referencia:';
            app.DistanciaEditFieldLabel = uilabel(app.LeftPanel);
            app.DistanciaEditFieldLabel.Text = 'Distancia';
            app.DistanciaEditField = uieditfield(app.LeftPanel, 'numeric');
            app.KmLabel = uilabel(app.LeftPanel);
            app.KmLabel.Text = '  Km';
            app.FijarDatosButton = uibutton(app.LeftPanel, 'push');
            app.FijarDatosButton.ButtonPushedFcn = createCallbackFcn(app, @FijarDatosButtonPushed, true);
            app.FijarDatosButton.Text = 'Fijar Datos';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = 'test.PNG';
            app.LatitudEditFieldLabel = uilabel(app.LeftPanel);
            app.LatitudEditFieldLabel.Text = 'Latitud';
            app.LatitudEditField = uieditfield(app.LeftPanel, 'numeric');
            app.Label_9 = uilabel(app.LeftPanel);
            app.Label_9.Text = '°';
            app.ElevacionEditFieldLabel = uilabel(app.LeftPanel);
            app.ElevacionEditFieldLabel.Text = 'Elevacion';
            app.ElevacionEditField = uieditfield(app.LeftPanel, 'numeric');
            app.Label_14 = uilabel(app.LeftPanel);
            app.Label_14.Text = '°';
            app.BandaDropDownLabel = uilabel(app.LeftPanel);
            app.BandaDropDownLabel.Text = 'Banda';
            app.BandaDropDown = uidropdown(app.LeftPanel);
            app.BandaDropDown.Items = {'', 'C', 'Ku', 'Ka', 'L'};
            app.BandaDropDown.ValueChangedFcn = createCallbackFcn(app, @BandaDropDownValueChanged, true);
            app.BandaDropDown.Value = '';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup3 = uitabgroup(app.RightPanel);
            app.PerdidasTab = uitab(app.TabGroup3);
            app.PerdidasTab.Title = 'Perdidas';
            app.TabGroup4 = uitabgroup(app.PerdidasTab);
            app.BasicasTab = uitab(app.TabGroup4);
            app.BasicasTab.Title = 'Basicas';
            app.CalcularButton_2 = uibutton(app.BasicasTab, 'push');
            app.CalcularButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_2Pushed, true);
            app.CalcularButton_2.Text = 'Calcular';
            app.libre = uieditfield(app.BasicasTab, 'numeric');
            app.dBLabel_4 = uilabel(app.BasicasTab);
            app.dBLabel_4.Text = 'dB';
            app.Lfs_downEditFieldLabel = uilabel(app.BasicasTab);
            app.Lfs_downEditFieldLabel.Text = 'Lfs_down';
            app.Lfs_downEditField = uieditfield(app.BasicasTab, 'numeric');
            app.Lfs_upLabel = uilabel(app.BasicasTab);
            app.Lfs_upLabel.Text = 'Lfs_up';
            app.dBLabel_11 = uilabel(app.BasicasTab);
            app.dBLabel_11.Text = 'dB';
            app.DesapuntamientoTab = uitab(app.TabGroup4);
            app.DesapuntamientoTab.Title = 'Desapuntamiento';
            app.DesapenTxEditFieldLabel = uilabel(app.DesapuntamientoTab);
            app.DesapenTxEditFieldLabel.Text = 'Desap en Tx';
            app.DesapenTxEditField = uieditfield(app.DesapuntamientoTab, 'numeric');
            app.CalcularButton_3 = uibutton(app.DesapuntamientoTab, 'push');
            app.CalcularButton_3.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_3Pushed, true);
            app.CalcularButton_3.Text = 'Calcular';
            app.DesapenRxEditFieldLabel = uilabel(app.DesapuntamientoTab);
            app.DesapenRxEditFieldLabel.Text = 'Desap en Rx';
            app.DesapenRxEditField = uieditfield(app.DesapuntamientoTab, 'numeric');
            app.Ancho3dBEditFieldLabel = uilabel(app.DesapuntamientoTab);
            app.Ancho3dBEditFieldLabel.Text = ' Ancho 3dB';
            app.Ancho3dBEditField = uieditfield(app.DesapuntamientoTab, 'numeric');
            app.desap = uieditfield(app.DesapuntamientoTab, 'numeric');
            app.dBLabel_5 = uilabel(app.DesapuntamientoTab);
            app.dBLabel_5.Text = 'dB';
            app.Label_5 = uilabel(app.DesapuntamientoTab);
            app.Label_5.Text = '°';
            app.Label_6 = uilabel(app.DesapuntamientoTab);
            app.Label_6.Text = '°';
            app.Label_7 = uilabel(app.DesapuntamientoTab);
            app.Label_7.Text = '°';
            app.GasesTab_2 = uitab(app.TabGroup4);
            app.GasesTab_2.Title = 'Gases';
            app.CalcularButton_4 = uibutton(app.GasesTab_2, 'push');
            app.CalcularButton_4.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_4Pushed, true);
            app.CalcularButton_4.Text = 'Calcular';
            app.pgases = uieditfield(app.GasesTab_2, 'numeric');
            app.dBLabel_6 = uilabel(app.GasesTab_2);
            app.dBLabel_6.Text = 'dB';
            app.PresionEditFieldLabel = uilabel(app.GasesTab_2);
            app.PresionEditFieldLabel.Text = 'Presion';
            app.PresionEditField = uieditfield(app.GasesTab_2, 'numeric');
            app.DensidaddelAguaEditFieldLabel = uilabel(app.GasesTab_2);
            app.DensidaddelAguaEditFieldLabel.Text = 'Densidad del Agua';
            app.DensidadEditField = uieditfield(app.GasesTab_2, 'numeric');
            app.DensidadEditField.Value = 7.5;
            app.TemperaturaEditField_2Label = uilabel(app.GasesTab_2);
            app.TemperaturaEditField_2Label.Text = 'Temperatura';
            app.TemperaturaEditField_2 = uieditfield(app.GasesTab_2, 'numeric');
            app.hPaLabel_2 = uilabel(app.GasesTab_2);
            app.hPaLabel_2.Text = '  hPa';
            app.gm3Label_2 = uilabel(app.GasesTab_2);
            app.gm3Label_2.Text = '  g/m^3';
            app.CLabel_2 = uilabel(app.GasesTab_2);
            app.CLabel_2.Text = '  °C';
            app.UplinkEditFieldLabel = uilabel(app.GasesTab_2);
            app.UplinkEditFieldLabel.Text = 'Uplink';
            app.GasesUplinkEditField = uieditfield(app.GasesTab_2, 'numeric');
            app.DownlinkLabel_2 = uilabel(app.GasesTab_2);
            app.DownlinkLabel_2.Text = 'Downlink';
            app.dBLabel_12 = uilabel(app.GasesTab_2);
            app.dBLabel_12.Text = 'dB';
            app.LluviaTab_2 = uitab(app.TabGroup4);
            app.LluviaTab_2.Title = 'Lluvia';
            app.KmLabel_2 = uilabel(app.LluviaTab_2);
            app.KmLabel_2.Text = 'Km';
            app.Label_8 = uilabel(app.LluviaTab_2);
            app.Label_8.Text = '%';
            app.mmhLabel = uilabel(app.LluviaTab_2);
            app.mmhLabel.Text = 'mm/h';
            app.CalcularButton_6 = uibutton(app.LluviaTab_2, 'push');
            app.CalcularButton_6.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_6Pushed, true);
            app.CalcularButton_6.Text = 'Calcular';
            app.downplluvia = uieditfield(app.LluviaTab_2, 'numeric');
            app.dBLabel_8 = uilabel(app.LluviaTab_2);
            app.dBLabel_8.Text = 'dB';
            app.Label_13 = uilabel(app.LluviaTab_2);
            app.Label_13.Text = '°';
            app.FigH0Button = uibutton(app.LluviaTab_2, 'push');
            app.FigH0Button.ButtonPushedFcn = createCallbackFcn(app, @FigH0ButtonPushed, true);
            app.FigH0Button.Text = 'Fig. H0';
            app.TablaKyAButton = uibutton(app.LluviaTab_2, 'push');
            app.TablaKyAButton.ButtonPushedFcn = createCallbackFcn(app, @TablaKyAButtonPushed, true);
            app.TablaKyAButton.Text = 'Tabla K y A';
            app.PBajdaLabel = uilabel(app.LluviaTab_2);
            app.PBajdaLabel.Text = 'P. Bajda';
            app.Up_plluvia = uieditfield(app.LluviaTab_2, 'numeric');
            app.dBLabel_10 = uilabel(app.LluviaTab_2);
            app.dBLabel_10.Text = 'dB';
            app.PSubidaLabel = uilabel(app.LluviaTab_2);
            app.PSubidaLabel.Text = 'P. Subida';
            app.ITUP837Button = uibutton(app.LluviaTab_2, 'push');
            app.ITUP837Button.ButtonPushedFcn = createCallbackFcn(app, @ITUP837ButtonPushed, true);
            app.ITUP837Button.Text = 'ITU P.837';
            app.FactorKupEditFieldLabel = uilabel(app.LluviaTab_2);
            app.FactorKupEditFieldLabel.Text = 'Factor K up';
            app.FactorKupEditField = uieditfield(app.LluviaTab_2, 'numeric');
            app.FactorAupEditFieldLabel = uilabel(app.LluviaTab_2);
            app.FactorAupEditFieldLabel.Text = 'Factor A up';
            app.FactorAupEditField = uieditfield(app.LluviaTab_2, 'numeric');
            app.AlturahsEditFieldLabel = uilabel(app.LluviaTab_2);
            app.AlturahsEditFieldLabel.Text = 'Altura (hs)';
            app.AlturahsEditField = uieditfield(app.LluviaTab_2, 'numeric');
            app.h0EditFieldLabel = uilabel(app.LluviaTab_2);
            app.h0EditFieldLabel.Text = 'h0';
            app.h0EditField = uieditfield(app.LluviaTab_2, 'numeric');
            app.IndiponibilidadpEditFieldLabel = uilabel(app.LluviaTab_2);
            app.IndiponibilidadpEditFieldLabel.Text = 'Indiponibilidad (p)';
            app.IndiponibilidadpEditField = uieditfield(app.LluviaTab_2, 'numeric');
            app.IntensidadLluviaEditFieldLabel = uilabel(app.LluviaTab_2);
            app.IntensidadLluviaEditFieldLabel.Text = 'Intensidad Lluvia';
            app.IntensidadLluviaEditField = uieditfield(app.LluviaTab_2, 'numeric');
            app.LongitudEditFieldLabel = uilabel(app.LluviaTab_2);
            app.LongitudEditFieldLabel.Text = 'Longitud';
            app.LongitudEditField = uieditfield(app.LluviaTab_2, 'numeric');
            app.FactorKdownEditFieldLabel = uilabel(app.LluviaTab_2);
            app.FactorKdownEditFieldLabel.Text = 'Factor K down';
            app.FactorKdownEditField = uieditfield(app.LluviaTab_2, 'numeric');
            app.FactorAdownEditFieldLabel = uilabel(app.LluviaTab_2);
            app.FactorAdownEditFieldLabel.Text = 'Factor A down';
            app.FactorAdownEditField = uieditfield(app.LluviaTab_2, 'numeric');
            app.OtrasPerdTab = uitab(app.TabGroup4);
            app.OtrasPerdTab.Title = 'Otras Perd';
            app.ResultOtrasper = uieditfield(app.OtrasPerdTab, 'numeric');
            app.CalcularOtrasper = uibutton(app.OtrasPerdTab, 'push');
            app.CalcularOtrasper.ButtonPushedFcn = createCallbackFcn(app, @CalcularOtrasperButtonPushed, true);
            app.CalcularOtrasper.Text = 'Calcular';
            app.CentelleoLabel = uilabel(app.OtrasPerdTab);
            app.CentelleoLabel.Text = 'Centelleo:';
            app.UplinkEditFieldLabel_2 = uilabel(app.OtrasPerdTab);
            app.UplinkEditFieldLabel_2.Text = 'Uplink';
            app.CentelleoUplinkEditField = uieditfield(app.OtrasPerdTab, 'numeric');
            app.DownlinkLabel_3 = uilabel(app.OtrasPerdTab);
            app.DownlinkLabel_3.Text = 'Downlink';
            app.TotalTab_2 = uitab(app.TabGroup4);
            app.TotalTab_2.Title = 'Total';
            app.CalcularButton_5 = uibutton(app.TotalTab_2, 'push');
            app.CalcularButton_5.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_5Pushed, true);
            app.CalcularButton_5.Text = 'Calcular';
            app.ptotal = uieditfield(app.TotalTab_2, 'numeric');
            app.dBLabel_7 = uilabel(app.TotalTab_2);
            app.dBLabel_7.Text = 'dB';
            app.UplinkEditFieldLabel_3 = uilabel(app.TotalTab_2);
            app.UplinkEditFieldLabel_3.Text = 'Uplink';
            app.UplinkTotalEditField = uieditfield(app.TotalTab_2, 'numeric');
            app.DownlinkLabel_4 = uilabel(app.TotalTab_2);
            app.DownlinkLabel_4.Text = 'Downlink';
            app.RuidoTab = uitab(app.TabGroup3);
            app.RuidoTab.Title = 'Ruido';
            app.TabGroup5 = uitabgroup(app.RuidoTab);
            app.AntenaTab = uitab(app.TabGroup5);
            app.AntenaTab.Title = 'Antena';
            app.Switch = uiswitch(app.AntenaTab, 'slider');
            app.Switch.Items = {'Con Lluvia', 'Cielo Despejado'};
            app.Switch.Value = 'Con Lluvia';
            app.CalcularButton_8 = uibutton(app.AntenaTab, 'push');
            app.CalcularButton_8.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_8Pushed, true);
            app.CalcularButton_8.Text = 'Calcular';
            app.res_antena = uieditfield(app.AntenaTab, 'numeric');
            app.TgEditFieldLabel = uilabel(app.AntenaTab);
            app.TgEditFieldLabel.Text = 'Tg';
            app.TgEditField = uieditfield(app.AntenaTab, 'numeric');
            app.TgEditField.Value = 290;
            app.GlsEditFieldLabel = uilabel(app.AntenaTab);
            app.GlsEditFieldLabel.Text = 'Gls';
            app.GlsEditField = uieditfield(app.AntenaTab, 'numeric');
            app.TskyEditFieldLabel = uilabel(app.AntenaTab);
            app.TskyEditFieldLabel.Text = 'Tsky';
            app.TskyEditField = uieditfield(app.AntenaTab, 'numeric');
            app.TskyEditField.Value = 17;
            app.TatmEditFieldLabel = uilabel(app.AntenaTab);
            app.TatmEditFieldLabel.Text = 'Tatm';
            app.TatmEditField = uieditfield(app.AntenaTab, 'numeric');
            app.TatmEditField.Value = 270;
            app.TwacoploEditFieldLabel = uilabel(app.AntenaTab);
            app.TwacoploEditFieldLabel.Text = 'Tw(acoplo)';
            app.TwacoploEditField = uieditfield(app.AntenaTab, 'numeric');
            app.wacoploEditFieldLabel = uilabel(app.AntenaTab);
            app.wacoploEditFieldLabel.Text = 'w(acoplo)';
            app.wacoploEditField = uieditfield(app.AntenaTab, 'numeric');
            app.ReceptorTab = uitab(app.TabGroup5);
            app.ReceptorTab.Title = 'Receptor';
            app.ValoresLabel = uilabel(app.ReceptorTab);
            app.ValoresLabel.Text = 'Valores';
            app.Valores_g = uieditfield(app.ReceptorTab, 'text');
            app.EjsillggLabel = uilabel(app.ReceptorTab);
            app.EjsillggLabel.Text = 'Ej: si [l l g g]';
            app.CalcularButton_7 = uibutton(app.ReceptorTab, 'push');
            app.CalcularButton_7.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_7Pushed, true);
            app.CalcularButton_7.Text = 'Calcular';
            app.ResultadoRuido = uieditfield(app.ReceptorTab, 'numeric');
            app.Label_12 = uilabel(app.ReceptorTab);
            app.Label_12.Text = '/°';
            app.OrdenEditFieldLabel = uilabel(app.ReceptorTab);
            app.OrdenEditFieldLabel.Text = 'Orden';
            app.Orden = uieditfield(app.ReceptorTab, 'text');
            app.TemperaturaEditField_3Label = uilabel(app.ReceptorTab);
            app.TemperaturaEditField_3Label.Text = 'Temperatura';
            app.TemperaturaRuido = uieditfield(app.ReceptorTab, 'text');
            app.Siaplica2sinoescribir0Label = uilabel(app.ReceptorTab);
            app.Siaplica2sinoescribir0Label.Text = '(Si aplica *[2], sino escribir 0)';
            app.Label_11 = uilabel(app.ReceptorTab);
            app.Label_11.Text = 'Escriba en orden y separado con espacio, los valores correspondientes';
            app.Ej12151Label = uilabel(app.ReceptorTab);
            app.Ej12151Label.Text = 'Ej: [1.2 1.5 ...] *[1]';
            app.TodoslosvaloresdebenestarensuspropiostrminosyenvecesLabel = uilabel(app.ReceptorTab);
            app.TodoslosvaloresdebenestarensuspropiostrminosyenvecesLabel.Text = '[1]: Todos los valores deben estar en sus propios términos, y en veces.';
            app.AplicacuandoenelordenexistegLabel = uilabel(app.ReceptorTab);
            app.AplicacuandoenelordenexistegLabel.Text = '[2]: Aplica cuando en el orden existe ''g'' ';
            app.SiesgelvaloresenglomismoparaLNohacerconversionesLabel = uilabel(app.ReceptorTab);
            app.SiesgelvaloresenglomismoparaLNohacerconversionesLabel.Text = 'Si es g el valor es en g, lo mismo para L. No hacer conversiones.';
            app.RuidoSistemaTab = uitab(app.TabGroup5);
            app.RuidoSistemaTab.Title = 'Ruido Sistema';
            app.TemperaturadeBrilloEditFieldLabel = uilabel(app.RuidoSistemaTab);
            app.TemperaturadeBrilloEditFieldLabel.Text = 'Temperatura de Brillo';
            app.TemperaturadeBrilloEditField = uieditfield(app.RuidoSistemaTab, 'numeric');
            app.TemperaturadeBrilloEditField.Value = 290;
            app.CalcularButton_9 = uibutton(app.RuidoSistemaTab, 'push');
            app.CalcularButton_9.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_9Pushed, true);
            app.CalcularButton_9.Text = 'Calcular';
            app.EnlacedeSubidaEditFieldLabel = uilabel(app.RuidoSistemaTab);
            app.EnlacedeSubidaEditFieldLabel.Text = 'Enlace de Subida';
            app.EnlacedeSubidaEditField = uieditfield(app.RuidoSistemaTab, 'numeric');
            app.EnlacedeBajadaEditFieldLabel = uilabel(app.RuidoSistemaTab);
            app.EnlacedeBajadaEditFieldLabel.Text = 'Enlace de Bajada';
            app.EnlacedeBajadaEditField = uieditfield(app.RuidoSistemaTab, 'numeric');
            app.BackoffTab = uitab(app.TabGroup5);
            app.BackoffTab.Title = 'Backoff';
            app.Switch_2 = uiswitch(app.BackoffTab, 'slider');
            app.Switch_2.Items = {'TDMA', 'FDMA'};
            app.Switch_2.Value = 'TDMA';
            app.CalcularButton_10 = uibutton(app.BackoffTab, 'push');
            app.CalcularButton_10.ButtonPushedFcn = createCallbackFcn(app, @CalcularButton_10Pushed, true);
            app.CalcularButton_10.Text = 'Calcular';
            app.PireinEditFieldLabel = uilabel(app.BackoffTab);
            app.PireinEditFieldLabel.Text = 'Pire in';
            app.PireinEditField = uieditfield(app.BackoffTab, 'numeric');
            app.PireoutEditFieldLabel = uilabel(app.BackoffTab);
            app.PireoutEditFieldLabel.Text = 'Pire out';
            app.PireoutEditField = uieditfield(app.BackoffTab, 'numeric');
            app.IBEditFieldLabel = uilabel(app.BackoffTab);
            app.IBEditFieldLabel.Text = 'IB';
            app.IBEditField = uieditfield(app.BackoffTab, 'numeric');
            app.OBEditFieldLabel = uilabel(app.BackoffTab);
            app.OBEditFieldLabel.Text = 'OB';
            app.OBEditField = uieditfield(app.BackoffTab, 'numeric');
            app.pireinsat = uieditfield(app.BackoffTab, 'numeric');
            app.pireoutsat = uieditfield(app.BackoffTab, 'numeric');
            app.PireinsatLabel = uilabel(app.BackoffTab);
            app.PireinsatLabel.Text = 'Pire in sat';
            app.PireoutsatLabel = uilabel(app.BackoffTab);
            app.PireoutsatLabel.Text = 'Pire out sat';
            app.DeEntradaLabel = uilabel(app.BackoffTab);
            app.DeEntradaLabel.Text = 'De Entrada';
            app.dBmLabel = uilabel(app.BackoffTab);
            app.dBmLabel.Text = 'dBm';
            app.dBmLabel_2 = uilabel(app.BackoffTab);
            app.dBmLabel_2.Text = 'dBm';
            app.ConPireptxgtxLabel = uilabel(app.BackoffTab);
            app.ConPireptxgtxLabel.Text = 'Con Pire=ptx*gtx';
            app.GTTab = uitab(app.TabGroup5);
            app.GTTab.Title = 'G/T';
            app.GananciaEstacionTerrenaEditField = uieditfield(app.GTTab, 'numeric');
            app.GananciaSateliteEditFieldLabel = uilabel(app.GTTab);
            app.GananciaSateliteEditFieldLabel.Text = 'Ganancia Satelite';
            app.GananciaSateliteEditField = uieditfield(app.GTTab, 'numeric');
            app.DownlinkLabel = uilabel(app.GTTab);
            app.DownlinkLabel.Text = 'Downlink';
            app.UplinkLabel = uilabel(app.GTTab);
            app.UplinkLabel.Text = 'Uplink';
            app.CalcularGT = uibutton(app.GTTab, 'push');
            app.CalcularGT.ButtonPushedFcn = createCallbackFcn(app, @CalcularGTPushed, true);
            app.CalcularGT.Text = 'Calcular';
            app.ResultGT = uieditfield(app.GTTab, 'numeric');
            app.Switch_3 = uiswitch(app.GTTab, 'slider');
            app.Switch_3.Items = {'Estacion', 'Satelite'};
            app.Switch_3.Value = 'Estacion';
            app.GananciaEstacionTerrenaLabel = uilabel(app.GTTab);
            app.GananciaEstacionTerrenaLabel.Text = 'Ganancia Estacion Terrena';
            app.Dejaren0sinosedesealaGTobjetivoLabel = uilabel(app.GTTab);
            app.Dejaren0sinosedesealaGTobjetivoLabel.Text = 'Dejar en 0 si no se desea la G/T objetivo.';
            app.NotalagananciadebeestarendBLabel = uilabel(app.GTTab);
            app.NotalagananciadebeestarendBLabel.Text = 'Nota: la ganancia debe estar en dB.';
            app.dBLabel_9 = uilabel(app.GTTab);
            app.dBLabel_9.Text = 'dB';
            app.CNTab = uitab(app.TabGroup3);
            app.CNTab.Title = 'C/N';
            app.TabGroup6 = uitabgroup(app.CNTab);
            app.UpLinkTab = uitab(app.TabGroup6);
            app.UpLinkTab.Title = 'UpLink';
            app.CalcularCNUp = uibutton(app.UpLinkTab, 'push');
            app.CalcularCNUp.ButtonPushedFcn = createCallbackFcn(app, @CalcularCNUpButtonPushed, true);
            app.CalcularCNUp.Text = 'Calcular';
            app.ResultCnUp = uieditfield(app.UpLinkTab, 'numeric');
            app.PIREDdEditFieldLabel = uilabel(app.UpLinkTab);
            app.PIREDdEditFieldLabel.Text = 'PIREDd';
            app.PIREDdEditField = uieditfield(app.UpLinkTab, 'numeric');
            app.OBEditField_2Label = uilabel(app.UpLinkTab);
            app.OBEditField_2Label.Text = 'OB';
            app.OBEditField_2 = uieditfield(app.UpLinkTab, 'numeric');
            app.LfdEditFieldLabel = uilabel(app.UpLinkTab);
            app.LfdEditFieldLabel.Text = 'Lfd';
            app.LfdEditField = uieditfield(app.UpLinkTab, 'numeric');
            app.LdEditFieldLabel = uilabel(app.UpLinkTab);
            app.LdEditFieldLabel.Text = 'Ld';
            app.LdEditField = uieditfield(app.UpLinkTab, 'numeric');
            app.GTEditFieldLabel = uilabel(app.UpLinkTab);
            app.GTEditFieldLabel.Text = 'GT';
            app.GTEditField = uieditfield(app.UpLinkTab, 'numeric');
            app.DownlinkTab = uitab(app.TabGroup6);
            app.DownlinkTab.Title = 'Downlink';
            app.CalcularCnD = uibutton(app.DownlinkTab, 'push');
            app.CalcularCnD.ButtonPushedFcn = createCallbackFcn(app, @CalcularCnDButtonPushed, true);
            app.CalcularCnD.Text = 'Calcular';
            app.EditField_3 = uieditfield(app.DownlinkTab, 'numeric');
            app.TotalTab_3 = uitab(app.TabGroup6);
            app.TotalTab_3.Title = 'Total';
            app.EditField = uieditfield(app.TotalTab_3, 'numeric');
            app.CalcularButton_11 = uibutton(app.TotalTab_3, 'push');
            app.CalcularButton_11.Text = 'Calcular';
            app.CapacidadTab = uitab(app.TabGroup3);
            app.CapacidadTab.Title = 'Capacidad';
            app.CloseButton = uibutton(app.RightPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.PatientsMedicalSurveyUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = interfaz_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PatientsMedicalSurveyUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PatientsMedicalSurveyUIFigure)
        end
    end
end